classdef suspOpt_App_Design_Space_Explore < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SuspensionDesignExploreAI_App  matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        AISurrogateTab                 matlab.ui.container.Tab
        RideMetricLabel                matlab.ui.control.Label
        RollMetricLabel                matlab.ui.control.Label
        MetrictoPlotDropDown           matlab.ui.control.DropDown
        MetrictoPlotDropDownLabel      matlab.ui.control.Label
        PlotResponseSurfaceButton      matlab.ui.control.Button
        UIAxesResponseSurface          matlab.ui.control.UIAxes
        MultibodyTab                   matlab.ui.container.Tab
        TestMultibodyModelButton       matlab.ui.control.Button
        MultibodyPlotsBodyAccel        matlab.ui.control.UIAxes
        MultibodyPlotsRollAngle        matlab.ui.control.UIAxes
        YButtonGroup                   matlab.ui.container.ButtonGroup
        DesignParY10                   matlab.ui.control.RadioButton
        DesignParY09                   matlab.ui.control.RadioButton
        DesignParY08                   matlab.ui.control.RadioButton
        DesignParY07                   matlab.ui.control.RadioButton
        DesignParY06                   matlab.ui.control.RadioButton
        DesignParY05                   matlab.ui.control.RadioButton
        DesignParY04                   matlab.ui.control.RadioButton
        DesignParY03                   matlab.ui.control.RadioButton
        DesignParY02                   matlab.ui.control.RadioButton
        DesignParY01                   matlab.ui.control.RadioButton
        XButtonGroup                   matlab.ui.container.ButtonGroup
        DesignParX10                   matlab.ui.control.RadioButton
        DesignParX09                   matlab.ui.control.RadioButton
        DesignParX08                   matlab.ui.control.RadioButton
        DesignParX07                   matlab.ui.control.RadioButton
        DesignParX06                   matlab.ui.control.RadioButton
        DesignParX05                   matlab.ui.control.RadioButton
        DesignParX04                   matlab.ui.control.RadioButton
        DesignParX03                   matlab.ui.control.RadioButton
        DesignParX02                   matlab.ui.control.RadioButton
        DesignParX01                   matlab.ui.control.RadioButton
        EditFieldSlider10              matlab.ui.control.NumericEditField
        EditFieldSlider09              matlab.ui.control.NumericEditField
        EditFieldSlider08              matlab.ui.control.NumericEditField
        EditFieldSlider07              matlab.ui.control.NumericEditField
        EditFieldSlider06              matlab.ui.control.NumericEditField
        EditFieldSlider05              matlab.ui.control.NumericEditField
        EditFieldSlider04              matlab.ui.control.NumericEditField
        EditFieldSlider03              matlab.ui.control.NumericEditField
        EditFieldSlider02              matlab.ui.control.NumericEditField
        EditFieldSlider01              matlab.ui.control.NumericEditField
        SuspensionDesignSpaceExplorationLabel  matlab.ui.control.Label
        SliderDesignParam10            matlab.ui.control.Slider
        Slider10Label                  matlab.ui.control.Label
        SliderDesignParam09            matlab.ui.control.Slider
        Slider09Label                  matlab.ui.control.Label
        SliderDesignParam08            matlab.ui.control.Slider
        Slider08Label                  matlab.ui.control.Label
        SliderDesignParam07            matlab.ui.control.Slider
        Slider07Label                  matlab.ui.control.Label
        SliderDesignParam06            matlab.ui.control.Slider
        Slider06Label                  matlab.ui.control.Label
        SliderDesignParam05            matlab.ui.control.Slider
        Slider05Label                  matlab.ui.control.Label
        SliderDesignParam04            matlab.ui.control.Slider
        Slider04Label                  matlab.ui.control.Label
        SliderDesignParam03            matlab.ui.control.Slider
        Slider03Label                  matlab.ui.control.Label
        SliderDesignParam02            matlab.ui.control.Slider
        Slider02Label                  matlab.ui.control.Label
        SliderDesignParam01            matlab.ui.control.Slider
        Slider1Label                   matlab.ui.control.Label
    end

    
    methods (Access = private)
        
        function CheckResponseSurfaceUpdate(app,event)
            if(strcmp(app.TabGroup.SelectedTab.Title,'AI Surrogate'))
                PlotResponseSurfaceButtonPushed(app,event);
            end 
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load("parTableVal_trimSensitivity.mat");
            parTableVal = parTableVal_trim;
            useRows    = parTableVal.Use == true;
            inflParTbl = sortrows(parTableVal(useRows,:));

            app.Slider1Label.Text = ...
                suspOpt_param_param2Desc(parTableVal,inflParTbl(1,"Label").Variables);
            app.SliderDesignParam01.Limits = ...
                [inflParTbl(1,"Min").Variables; inflParTbl(1,"Max").Variables];
            app.EditFieldSlider01.Limits = app.SliderDesignParam01.Limits;
            app.SliderDesignParam01.Value = inflParTbl(1,"Default").Variables;
            app.EditFieldSlider01.Value = app.SliderDesignParam01.Value;
            app.SliderDesignParam01.Tag   = char(inflParTbl(1,"Label").Variables);

            app.Slider02Label.Text = ...
                suspOpt_param_param2Desc(parTableVal,inflParTbl(2,"Label").Variables);
            app.SliderDesignParam02.Limits = ...
                [inflParTbl(2,"Min").Variables; inflParTbl(2,"Max").Variables];
            app.EditFieldSlider02.Limits = app.SliderDesignParam02.Limits;
            app.SliderDesignParam02.Value = inflParTbl(2,"Default").Variables;
            app.EditFieldSlider02.Value = app.SliderDesignParam02.Value;
            app.SliderDesignParam02.Tag   = char(inflParTbl(2,"Label").Variables);

            app.Slider03Label.Text = ...
                suspOpt_param_param2Desc(parTableVal,inflParTbl(3,"Label").Variables);
            app.SliderDesignParam03.Limits = ...
                [inflParTbl(3,"Min").Variables; inflParTbl(3,"Max").Variables];
            app.EditFieldSlider03.Limits = app.SliderDesignParam03.Limits;
            app.SliderDesignParam03.Value = inflParTbl(3,"Default").Variables;
            app.EditFieldSlider03.Value = app.SliderDesignParam03.Value;
            app.SliderDesignParam03.Tag   = char(inflParTbl(3,"Label").Variables);

            app.Slider04Label.Text = ...
                suspOpt_param_param2Desc(parTableVal,inflParTbl(4,"Label").Variables);
            app.SliderDesignParam04.Limits = ...
                [inflParTbl(4,"Min").Variables; inflParTbl(4,"Max").Variables];
            app.EditFieldSlider04.Limits = app.SliderDesignParam04.Limits;
            app.SliderDesignParam04.Value = inflParTbl(4,"Default").Variables;
            app.EditFieldSlider04.Value = app.SliderDesignParam04.Value;
            app.SliderDesignParam04.Tag   = char(inflParTbl(4,"Label").Variables);

            app.Slider05Label.Text = ...
                suspOpt_param_param2Desc(parTableVal,inflParTbl(5,"Label").Variables);
            app.SliderDesignParam05.Limits = ...
                [inflParTbl(5,"Min").Variables; inflParTbl(5,"Max").Variables];
            app.EditFieldSlider05.Limits = app.SliderDesignParam05.Limits;
            app.SliderDesignParam05.Value = inflParTbl(5,"Default").Variables;
            app.EditFieldSlider05.Value = app.SliderDesignParam05.Value;
            app.SliderDesignParam05.Tag   = char(inflParTbl(5,"Label").Variables);

            app.Slider06Label.Text = ...
                suspOpt_param_param2Desc(parTableVal,inflParTbl(6,"Label").Variables);
            app.SliderDesignParam06.Limits = ...
                [inflParTbl(6,"Min").Variables; inflParTbl(6,"Max").Variables];
            app.EditFieldSlider06.Limits = app.SliderDesignParam06.Limits;
            app.SliderDesignParam06.Value = inflParTbl(6,"Default").Variables;
            app.EditFieldSlider06.Value = app.SliderDesignParam06.Value;
            app.SliderDesignParam06.Tag   = char(inflParTbl(6,"Label").Variables);

            app.Slider07Label.Text = ...
                suspOpt_param_param2Desc(parTableVal,inflParTbl(7,"Label").Variables);
            app.SliderDesignParam07.Limits = ...
                [inflParTbl(7,"Min").Variables; inflParTbl(7,"Max").Variables];
            app.EditFieldSlider07.Limits = app.SliderDesignParam07.Limits;
            app.SliderDesignParam07.Value = inflParTbl(7,"Default").Variables;
            app.EditFieldSlider07.Value = app.SliderDesignParam07.Value;
            app.SliderDesignParam07.Tag   = char(inflParTbl(7,"Label").Variables);

            app.Slider08Label.Text = ...
                suspOpt_param_param2Desc(parTableVal,inflParTbl(8,"Label").Variables);
            app.SliderDesignParam08.Limits = ...
                [inflParTbl(8,"Min").Variables; inflParTbl(8,"Max").Variables];
            app.SliderDesignParam08.Value = inflParTbl(8,"Default").Variables;
            app.EditFieldSlider08.Limits = app.SliderDesignParam08.Limits;
            app.EditFieldSlider08.Value = app.SliderDesignParam08.Value;
            app.SliderDesignParam08.Tag   = char(inflParTbl(8,"Label").Variables);

            app.Slider09Label.Text = ...
                suspOpt_param_param2Desc(parTableVal,inflParTbl(9,"Label").Variables);
            app.SliderDesignParam09.Limits = ...
                [inflParTbl(9,"Min").Variables; inflParTbl(9,"Max").Variables];
            app.EditFieldSlider09.Limits = app.SliderDesignParam09.Limits;
            app.SliderDesignParam09.Value = inflParTbl(9,"Default").Variables;
            app.EditFieldSlider09.Value = app.SliderDesignParam09.Value;
            app.SliderDesignParam09.Tag   = char(inflParTbl(9,"Label").Variables);

            app.Slider10Label.Text = ...
                suspOpt_param_param2Desc(parTableVal,inflParTbl(10,"Label").Variables);
            app.SliderDesignParam10.Limits = ...
                [inflParTbl(10,"Min").Variables; inflParTbl(10,"Max").Variables];
            app.EditFieldSlider10.Limits = app.SliderDesignParam10.Limits;
            app.SliderDesignParam10.Value = inflParTbl(10,"Default").Variables;
            app.EditFieldSlider10.Value = app.SliderDesignParam10.Value;
            app.SliderDesignParam10.Tag   = char(inflParTbl(10,"Label").Variables);

            TabGroupSelectionChanged(app,[])
        end

        % Button pushed function: TestMultibodyModelButton
        function TestMultibodyModelButtonPushed(app, event)
            load("parTableVal_trimSensitivity.mat");
            parTableVal = parTableVal_trim;
            setup_sm_car_model;
            Vehicle_data;
            Vehicle = sm_car_vehcfg_checkConfig(Vehicle);
            Vehicle = suspOpt_param_assign(parTableVal,app.SliderDesignParam01.Tag,app.SliderDesignParam01.Value,Vehicle);
            Vehicle = suspOpt_param_assign(parTableVal,app.SliderDesignParam02.Tag,app.SliderDesignParam02.Value,Vehicle);
            Vehicle = suspOpt_param_assign(parTableVal,app.SliderDesignParam03.Tag,app.SliderDesignParam03.Value,Vehicle);
            Vehicle = suspOpt_param_assign(parTableVal,app.SliderDesignParam04.Tag,app.SliderDesignParam04.Value,Vehicle);
            Vehicle = suspOpt_param_assign(parTableVal,app.SliderDesignParam05.Tag,app.SliderDesignParam05.Value,Vehicle);
            Vehicle = suspOpt_param_assign(parTableVal,app.SliderDesignParam06.Tag,app.SliderDesignParam06.Value,Vehicle);
            Vehicle = suspOpt_param_assign(parTableVal,app.SliderDesignParam07.Tag,app.SliderDesignParam07.Value,Vehicle);
            Vehicle = suspOpt_param_assign(parTableVal,app.SliderDesignParam08.Tag,app.SliderDesignParam08.Value,Vehicle);
            Vehicle = suspOpt_param_assign(parTableVal,app.SliderDesignParam09.Tag,app.SliderDesignParam09.Value,Vehicle);
            Vehicle = suspOpt_param_assign(parTableVal,app.SliderDesignParam10.Tag,app.SliderDesignParam10.Value,Vehicle);
            assignin('base','Vehicle',Vehicle);
            out=sim(mdl);
            [perfMetrics, perfData]  = suspOpt_calc_perf_metric(out,false);
            suspOpt_plot_RollRideMetric(perfData,perfMetrics,app.MultibodyPlotsRollAngle,app.MultibodyPlotsBodyAccel);
        end

        % Value changed function: EditFieldSlider01
        function EditFieldSlider01ValueChanged(app, event)
            value = app.EditFieldSlider01.Value;
            app.SliderDesignParam01.Value = value;
            CheckResponseSurfaceUpdate(app,event);
        end

        % Value changed function: EditFieldSlider02
        function EditFieldSlider02ValueChanged(app, event)
            value = app.EditFieldSlider02.Value;
            app.SliderDesignParam02.Value = value;
            CheckResponseSurfaceUpdate(app,event);                        
        end

        % Value changed function: EditFieldSlider03
        function EditFieldSlider03ValueChanged(app, event)
            value = app.EditFieldSlider03.Value;
            app.SliderDesignParam03.Value = value;
            CheckResponseSurfaceUpdate(app,event);                        
        end

        % Value changed function: EditFieldSlider04
        function EditFieldSlider04ValueChanged(app, event)
            value = app.EditFieldSlider04.Value;
            app.SliderDesignParam04.Value = value;
            CheckResponseSurfaceUpdate(app,event);
        end

        % Value changed function: EditFieldSlider05
        function EditFieldSlider05ValueChanged(app, event)
            value = app.EditFieldSlider05.Value;
            app.SliderDesignParam05.Value = value;            
            CheckResponseSurfaceUpdate(app,event);
        end

        % Value changed function: EditFieldSlider06
        function EditFieldSlider06ValueChanged(app, event)
            value = app.EditFieldSlider06.Value;
            app.SliderDesignParam06.Value = value;            
            CheckResponseSurfaceUpdate(app,event);
        end

        % Value changed function: EditFieldSlider07
        function EditFieldSlider07ValueChanged(app, event)
            value = app.EditFieldSlider07.Value;
            app.SliderDesignParam07.Value = value;            
            CheckResponseSurfaceUpdate(app,event);
        end

        % Value changed function: EditFieldSlider08
        function EditFieldSlider08ValueChanged(app, event)
            value = app.EditFieldSlider08.Value;
            app.SliderDesignParam08.Value = value;            
            CheckResponseSurfaceUpdate(app,event);
        end

        % Value changed function: EditFieldSlider09
        function EditFieldSlider09ValueChanged(app, event)
            value = app.EditFieldSlider09.Value;            
            app.SliderDesignParam09.Value = value;            
            CheckResponseSurfaceUpdate(app,event);
        end

        % Value changed function: EditFieldSlider10
        function EditFieldSlider10ValueChanged(app, event)
            value = app.EditFieldSlider10.Value;
            app.SliderDesignParam10.Value = value;            
            CheckResponseSurfaceUpdate(app,event);
        end

        % Value changed function: SliderDesignParam01
        function SliderDesignParam01ValueChanged(app, event)
            value = app.SliderDesignParam01.Value;
            app.EditFieldSlider01.Value = value;
            CheckResponseSurfaceUpdate(app,event);
        end

        % Value changed function: SliderDesignParam02
        function SliderDesignParam02ValueChanged(app, event)
            value = app.SliderDesignParam02.Value;
            app.EditFieldSlider02.Value = value;            
            CheckResponseSurfaceUpdate(app,event);
        end

        % Value changed function: SliderDesignParam03
        function SliderDesignParam03ValueChanged(app, event)
            value = app.SliderDesignParam03.Value;
            app.EditFieldSlider03.Value = value;            
            CheckResponseSurfaceUpdate(app,event);
        end

        % Value changed function: SliderDesignParam04
        function SliderDesignParam04ValueChanged(app, event)
            value = app.SliderDesignParam04.Value;
            app.EditFieldSlider04.Value = value;            
            CheckResponseSurfaceUpdate(app,event);
        end

        % Value changed function: SliderDesignParam05
        function SliderDesignParam05ValueChanged(app, event)
            value = app.SliderDesignParam05.Value;
            app.EditFieldSlider05.Value = value;            
            CheckResponseSurfaceUpdate(app,event);
        end

        % Value changed function: SliderDesignParam06
        function SliderDesignParam06ValueChanged(app, event)
            value = app.SliderDesignParam06.Value;
            app.EditFieldSlider06.Value = value;            
            CheckResponseSurfaceUpdate(app,event);
        end

        % Value changed function: SliderDesignParam07
        function SliderDesignParam07ValueChanged(app, event)
            value = app.SliderDesignParam07.Value;
            app.EditFieldSlider07.Value = value;            
            CheckResponseSurfaceUpdate(app,event);
        end

        % Value changed function: SliderDesignParam08
        function SliderDesignParam08ValueChanged(app, event)
            value = app.SliderDesignParam08.Value;
            app.EditFieldSlider08.Value = value;            
            CheckResponseSurfaceUpdate(app,event);
        end

        % Value changed function: SliderDesignParam09
        function SliderDesignParam09ValueChanged(app, event)
            value = app.SliderDesignParam09.Value;
            app.EditFieldSlider09.Value = value;            
            CheckResponseSurfaceUpdate(app,event);
        end

        % Value changed function: SliderDesignParam10
        function SliderDesignParam10ValueChanged(app, event)
            value = app.SliderDesignParam10.Value;
            app.EditFieldSlider10.Value = value;
            CheckResponseSurfaceUpdate(app,event);
        end

        % Callback function: PlotResponseSurfaceButton, XButtonGroup, 
        % ...and 1 other component
        function PlotResponseSurfaceButtonPushed(app, event)
            load("parTableVal_trimSensitivity.mat");
            parTableVal = parTableVal_trim;
            useRows    = parTableVal.Use == true;
            inflParTbl = sortrows(parTableVal(useRows,:));

            switch app.YButtonGroup.SelectedObject.Tag
                case 'DesignParY01', YParName = app.SliderDesignParam01.Tag;
                case 'DesignParY02', YParName = app.SliderDesignParam02.Tag;
                case 'DesignParY03', YParName = app.SliderDesignParam03.Tag;
                case 'DesignParY04', YParName = app.SliderDesignParam04.Tag;
                case 'DesignParY05', YParName = app.SliderDesignParam05.Tag;
                case 'DesignParY06', YParName = app.SliderDesignParam06.Tag;
                case 'DesignParY07', YParName = app.SliderDesignParam07.Tag;
                case 'DesignParY08', YParName = app.SliderDesignParam08.Tag;
                case 'DesignParY09', YParName = app.SliderDesignParam09.Tag;
                case 'DesignParY10', YParName = app.SliderDesignParam10.Tag;
            end
            switch app.XButtonGroup.SelectedObject.Tag
                case 'DesignParX01', XParName = app.SliderDesignParam01.Tag;
                case 'DesignParX02', XParName = app.SliderDesignParam02.Tag;
                case 'DesignParX03', XParName = app.SliderDesignParam03.Tag;
                case 'DesignParX04', XParName = app.SliderDesignParam04.Tag;
                case 'DesignParX05', XParName = app.SliderDesignParam05.Tag;
                case 'DesignParX06', XParName = app.SliderDesignParam06.Tag;
                case 'DesignParX07', XParName = app.SliderDesignParam07.Tag;
                case 'DesignParX08', XParName = app.SliderDesignParam08.Tag;
                case 'DesignParX09', XParName = app.SliderDesignParam09.Tag;
                case 'DesignParX10', XParName = app.SliderDesignParam10.Tag;
            end

            if (~strcmp(XParName,YParName))
                queryPtVals = [...
                    app.EditFieldSlider01.Value;
                    app.EditFieldSlider02.Value;
                    app.EditFieldSlider03.Value;
                    app.EditFieldSlider04.Value;
                    app.EditFieldSlider05.Value;
                    app.EditFieldSlider06.Value;
                    app.EditFieldSlider07.Value;
                    app.EditFieldSlider08.Value;
                    app.EditFieldSlider09.Value;
                    app.EditFieldSlider10.Value;
                    ];

                for i = 1:height(inflParTbl)
                    qpt.(char(inflParTbl(i,"Label").Variables)) = queryPtVals(i);
                end
                qpt_tbl = struct2table(qpt);

                perfMetricString = app.MetrictoPlotDropDown.Value + "Metric";

                load aiModels.mat                
                [rollM, rideM] = suspOpt_plot_Response_Surf(XParName,YParName,qpt_tbl,inflParTbl,perfMetricString,aiModels.gpModel,app.UIAxesResponseSurface);
                app.RollMetricLabel.Text = sprintf("Roll Metric:   %1.3f",rollM);
                app.RideMetricLabel.Text = sprintf("Ride Metric: %1.3f",rideM);
            end
        end

        % Value changed function: MetrictoPlotDropDown
        function MetrictoPlotDropDownValueChanged(app, event)
            value = app.MetrictoPlotDropDown.Value;
            PlotResponseSurfaceButtonPushed(app,event);
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            switch selectedTab.Title
                case 'AI Surrogate'
                    app.XButtonGroup.Enable = 'on';
                    app.YButtonGroup.Enable = 'on';
                    PlotResponseSurfaceButtonPushed(app,event);
                case 'Multibody'
                    app.XButtonGroup.Enable = 'off';
                    app.YButtonGroup.Enable = 'off';
            end            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SuspensionDesignExploreAI_App = uifigure('Visible', 'off');
            app.SuspensionDesignExploreAI_App.Name = 'Suspension Design Space Exploration';
            app.Slider1Label = uilabel(app.SuspensionDesignExploreAI_App);
            app.Slider1Label.Text = 'Slider01Label';
            app.SliderDesignParam01 = uislider(app.SuspensionDesignExploreAI_App);
            app.SliderDesignParam01.ValueChangedFcn = createCallbackFcn(app, @SliderDesignParam01ValueChanged, true);
            app.Slider02Label = uilabel(app.SuspensionDesignExploreAI_App);
            app.Slider02Label.Text = 'Slider02Label';
            app.SliderDesignParam02 = uislider(app.SuspensionDesignExploreAI_App);
            app.SliderDesignParam02.ValueChangedFcn = createCallbackFcn(app, @SliderDesignParam02ValueChanged, true);
            app.Slider03Label = uilabel(app.SuspensionDesignExploreAI_App);
            app.Slider03Label.Text = 'Slider03Label';
            app.SliderDesignParam03 = uislider(app.SuspensionDesignExploreAI_App);
            app.SliderDesignParam03.ValueChangedFcn = createCallbackFcn(app, @SliderDesignParam03ValueChanged, true);
            app.Slider04Label = uilabel(app.SuspensionDesignExploreAI_App);
            app.Slider04Label.Text = 'Slider04Label';
            app.SliderDesignParam04 = uislider(app.SuspensionDesignExploreAI_App);
            app.SliderDesignParam04.ValueChangedFcn = createCallbackFcn(app, @SliderDesignParam04ValueChanged, true);
            app.Slider05Label = uilabel(app.SuspensionDesignExploreAI_App);
            app.Slider05Label.Text = 'Slider05Label';
            app.SliderDesignParam05 = uislider(app.SuspensionDesignExploreAI_App);
            app.SliderDesignParam05.ValueChangedFcn = createCallbackFcn(app, @SliderDesignParam05ValueChanged, true);
            app.Slider06Label = uilabel(app.SuspensionDesignExploreAI_App);
            app.Slider06Label.Text = 'Slider06Label';
            app.SliderDesignParam06 = uislider(app.SuspensionDesignExploreAI_App);
            app.SliderDesignParam06.ValueChangedFcn = createCallbackFcn(app, @SliderDesignParam06ValueChanged, true);
            app.Slider07Label = uilabel(app.SuspensionDesignExploreAI_App);
            app.Slider07Label.Text = 'Slider07Label';
            app.SliderDesignParam07 = uislider(app.SuspensionDesignExploreAI_App);
            app.SliderDesignParam07.ValueChangedFcn = createCallbackFcn(app, @SliderDesignParam07ValueChanged, true);
            app.Slider08Label = uilabel(app.SuspensionDesignExploreAI_App);
            app.Slider08Label.Text = 'Slider08Label';
            app.SliderDesignParam08 = uislider(app.SuspensionDesignExploreAI_App);
            app.SliderDesignParam08.ValueChangedFcn = createCallbackFcn(app, @SliderDesignParam08ValueChanged, true);
            app.Slider09Label = uilabel(app.SuspensionDesignExploreAI_App);
            app.Slider09Label.Text = 'Slider09Label';
            app.SliderDesignParam09 = uislider(app.SuspensionDesignExploreAI_App);
            app.SliderDesignParam09.ValueChangedFcn = createCallbackFcn(app, @SliderDesignParam09ValueChanged, true);
            app.Slider10Label = uilabel(app.SuspensionDesignExploreAI_App);
            app.Slider10Label.Text = 'Slider10Label';
            app.SliderDesignParam10 = uislider(app.SuspensionDesignExploreAI_App);
            app.SliderDesignParam10.ValueChangedFcn = createCallbackFcn(app, @SliderDesignParam10ValueChanged, true);
            app.SuspensionDesignSpaceExplorationLabel = uilabel(app.SuspensionDesignExploreAI_App);
            app.SuspensionDesignSpaceExplorationLabel.Text = 'Suspension Design Space Exploration';
            app.EditFieldSlider01 = uieditfield(app.SuspensionDesignExploreAI_App, 'numeric');
            app.EditFieldSlider01.ValueChangedFcn = createCallbackFcn(app, @EditFieldSlider01ValueChanged, true);
            app.EditFieldSlider02 = uieditfield(app.SuspensionDesignExploreAI_App, 'numeric');
            app.EditFieldSlider02.ValueChangedFcn = createCallbackFcn(app, @EditFieldSlider02ValueChanged, true);
            app.EditFieldSlider03 = uieditfield(app.SuspensionDesignExploreAI_App, 'numeric');
            app.EditFieldSlider03.ValueChangedFcn = createCallbackFcn(app, @EditFieldSlider03ValueChanged, true);
            app.EditFieldSlider04 = uieditfield(app.SuspensionDesignExploreAI_App, 'numeric');
            app.EditFieldSlider04.ValueChangedFcn = createCallbackFcn(app, @EditFieldSlider04ValueChanged, true);
            app.EditFieldSlider05 = uieditfield(app.SuspensionDesignExploreAI_App, 'numeric');
            app.EditFieldSlider05.ValueChangedFcn = createCallbackFcn(app, @EditFieldSlider05ValueChanged, true);
            app.EditFieldSlider06 = uieditfield(app.SuspensionDesignExploreAI_App, 'numeric');
            app.EditFieldSlider06.ValueChangedFcn = createCallbackFcn(app, @EditFieldSlider06ValueChanged, true);
            app.EditFieldSlider07 = uieditfield(app.SuspensionDesignExploreAI_App, 'numeric');
            app.EditFieldSlider07.ValueChangedFcn = createCallbackFcn(app, @EditFieldSlider07ValueChanged, true);
            app.EditFieldSlider08 = uieditfield(app.SuspensionDesignExploreAI_App, 'numeric');
            app.EditFieldSlider08.ValueChangedFcn = createCallbackFcn(app, @EditFieldSlider08ValueChanged, true);
            app.EditFieldSlider09 = uieditfield(app.SuspensionDesignExploreAI_App, 'numeric');
            app.EditFieldSlider09.ValueChangedFcn = createCallbackFcn(app, @EditFieldSlider09ValueChanged, true);
            app.EditFieldSlider10 = uieditfield(app.SuspensionDesignExploreAI_App, 'numeric');
            app.EditFieldSlider10.ValueChangedFcn = createCallbackFcn(app, @EditFieldSlider10ValueChanged, true);
            app.XButtonGroup = uibuttongroup(app.SuspensionDesignExploreAI_App);
            app.XButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PlotResponseSurfaceButtonPushed, true);
            app.XButtonGroup.Title = 'X';
            app.DesignParX01 = uiradiobutton(app.XButtonGroup);
            app.DesignParX01.Tag = 'DesignParX01';
            app.DesignParX01.Text = '';
            app.DesignParX02 = uiradiobutton(app.XButtonGroup);
            app.DesignParX02.Tag = 'DesignParX02';
            app.DesignParX02.Text = '';
            app.DesignParX03 = uiradiobutton(app.XButtonGroup);
            app.DesignParX03.Tag = 'DesignParX03';
            app.DesignParX03.Text = '';
            app.DesignParX04 = uiradiobutton(app.XButtonGroup);
            app.DesignParX04.Tag = 'DesignParX04';
            app.DesignParX04.Text = '';
            app.DesignParX05 = uiradiobutton(app.XButtonGroup);
            app.DesignParX05.Tag = 'DesignParX05';
            app.DesignParX05.Text = '';
            app.DesignParX05.Value = true;
            app.DesignParX06 = uiradiobutton(app.XButtonGroup);
            app.DesignParX06.Tag = 'DesignParX06';
            app.DesignParX06.Text = '';
            app.DesignParX07 = uiradiobutton(app.XButtonGroup);
            app.DesignParX07.Tag = 'DesignParX07';
            app.DesignParX07.Text = '';
            app.DesignParX08 = uiradiobutton(app.XButtonGroup);
            app.DesignParX08.Tag = 'DesignParX08';
            app.DesignParX08.Text = '';
            app.DesignParX09 = uiradiobutton(app.XButtonGroup);
            app.DesignParX09.Tag = 'DesignParX09';
            app.DesignParX09.Text = '';
            app.DesignParX10 = uiradiobutton(app.XButtonGroup);
            app.DesignParX10.Tag = 'DesignParX10';
            app.DesignParX10.Text = '';
            app.YButtonGroup = uibuttongroup(app.SuspensionDesignExploreAI_App);
            app.YButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PlotResponseSurfaceButtonPushed, true);
            app.YButtonGroup.Title = 'Y';
            app.DesignParY01 = uiradiobutton(app.YButtonGroup);
            app.DesignParY01.Tag = 'DesignParY01';
            app.DesignParY01.Text = '';
            app.DesignParY02 = uiradiobutton(app.YButtonGroup);
            app.DesignParY02.Tag = 'DesignParY02';
            app.DesignParY02.Text = '';
            app.DesignParY03 = uiradiobutton(app.YButtonGroup);
            app.DesignParY03.Tag = 'DesignParY03';
            app.DesignParY03.Text = '';
            app.DesignParY04 = uiradiobutton(app.YButtonGroup);
            app.DesignParY04.Tag = 'DesignParY04';
            app.DesignParY04.Text = '';
            app.DesignParY04.Value = true;
            app.DesignParY05 = uiradiobutton(app.YButtonGroup);
            app.DesignParY05.Tag = 'DesignParY05';
            app.DesignParY05.Text = '';
            app.DesignParY06 = uiradiobutton(app.YButtonGroup);
            app.DesignParY06.Tag = 'DesignParY06';
            app.DesignParY06.Text = '';
            app.DesignParY07 = uiradiobutton(app.YButtonGroup);
            app.DesignParY07.Tag = 'DesignParY07';
            app.DesignParY07.Text = '';
            app.DesignParY08 = uiradiobutton(app.YButtonGroup);
            app.DesignParY08.Tag = 'DesignParY08';
            app.DesignParY08.Text = '';
            app.DesignParY09 = uiradiobutton(app.YButtonGroup);
            app.DesignParY09.Tag = 'DesignParY09';
            app.DesignParY09.Text = '';
            app.DesignParY10 = uiradiobutton(app.YButtonGroup);
            app.DesignParY10.Tag = 'DesignParY10';
            app.DesignParY10.Text = '';
            app.TabGroup = uitabgroup(app.SuspensionDesignExploreAI_App);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.AISurrogateTab = uitab(app.TabGroup);
            app.AISurrogateTab.Title = 'AI Surrogate';
            app.UIAxesResponseSurface = uiaxes(app.AISurrogateTab);
            title(app.UIAxesResponseSurface, 'Title')
            xlabel(app.UIAxesResponseSurface, 'X')
            ylabel(app.UIAxesResponseSurface, 'Y')
            zlabel(app.UIAxesResponseSurface, 'Z')
            app.PlotResponseSurfaceButton = uibutton(app.AISurrogateTab, 'push');
            app.PlotResponseSurfaceButton.ButtonPushedFcn = createCallbackFcn(app, @PlotResponseSurfaceButtonPushed, true);
            app.PlotResponseSurfaceButton.Text = 'Plot Response Surface';
            app.MetrictoPlotDropDownLabel = uilabel(app.AISurrogateTab);
            app.MetrictoPlotDropDownLabel.Text = 'Metric to Plot';
            app.MetrictoPlotDropDown = uidropdown(app.AISurrogateTab);
            app.MetrictoPlotDropDown.Items = {'Roll', 'Ride'};
            app.MetrictoPlotDropDown.ValueChangedFcn = createCallbackFcn(app, @MetrictoPlotDropDownValueChanged, true);
            app.MetrictoPlotDropDown.Value = 'Roll';
            app.RollMetricLabel = uilabel(app.AISurrogateTab);
            app.RollMetricLabel.Text = 'Roll Metric:';
            app.RideMetricLabel = uilabel(app.AISurrogateTab);
            app.RideMetricLabel.Text = 'Ride Metric:';
            app.MultibodyTab = uitab(app.TabGroup);
            app.MultibodyTab.Title = 'Multibody';
            app.MultibodyPlotsRollAngle = uiaxes(app.MultibodyTab);
            title(app.MultibodyPlotsRollAngle, 'Body Roll Angle')
            xlabel(app.MultibodyPlotsRollAngle, 'X')
            ylabel(app.MultibodyPlotsRollAngle, 'Y')
            zlabel(app.MultibodyPlotsRollAngle, 'Z')
            app.MultibodyPlotsBodyAccel = uiaxes(app.MultibodyTab);
            title(app.MultibodyPlotsBodyAccel, 'Body Acceleration')
            xlabel(app.MultibodyPlotsBodyAccel, 'X')
            ylabel(app.MultibodyPlotsBodyAccel, 'Y')
            zlabel(app.MultibodyPlotsBodyAccel, 'Z')
            app.TestMultibodyModelButton = uibutton(app.MultibodyTab, 'push');
            app.TestMultibodyModelButton.ButtonPushedFcn = createCallbackFcn(app, @TestMultibodyModelButtonPushed, true);
            app.TestMultibodyModelButton.Text = 'Test Multibody Model';
            % Show the figure after all components are created
            app.SuspensionDesignExploreAI_App.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = suspOpt_App_Design_Space_Explore

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.SuspensionDesignExploreAI_App)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.SuspensionDesignExploreAI_App)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SuspensionDesignExploreAI_App)
        end
    end
end